classdef Gendorg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GendorgInterface          matlab.ui.Figure
        AdjustAxisLimitsPanel     matlab.ui.container.Panel
        highLabel                 matlab.ui.control.Label
        lowLabel                  matlab.ui.control.Label
        LegendLabel               matlab.ui.control.Label
        TitleLabel                matlab.ui.control.Label
        TitleField                matlab.ui.control.EditField
        LegendField               matlab.ui.control.EditField
        EditTitleLegendButton     matlab.ui.control.Button
        IgnoreLimitsCheckBox      matlab.ui.control.CheckBox
        YaxisLimitLow             matlab.ui.control.NumericEditField
        YaxisLimitHigh            matlab.ui.control.NumericEditField
        YLabel                    matlab.ui.control.Label
        XaxisLimitLow             matlab.ui.control.NumericEditField
        XaxisLimitHigh            matlab.ui.control.NumericEditField
        XLabel                    matlab.ui.control.Label
        EditLimitsButton          matlab.ui.control.Button
        PlotsPanel                matlab.ui.container.Panel
        SaveFigureButton          matlab.ui.control.Button
        PlotDataButton            matlab.ui.control.Button
        TablePanel                matlab.ui.container.Panel
        DeleteTestButton          matlab.ui.control.Button
        AddTestButton             matlab.ui.control.Button
        LoadTableButton           matlab.ui.control.Button
        SaveTableButton           matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        ProcessDataTab            matlab.ui.container.Tab
        PlotSelectedTestButton    matlab.ui.control.Button
        SaveProcessedDataButton   matlab.ui.control.Button
        ProcessDataButtonGroup    matlab.ui.container.ButtonGroup
        RemoveOutliersButton      matlab.ui.control.Button
        SmoothDataButton          matlab.ui.control.Button
        CropDataButton            matlab.ui.control.Button
        ShiftDataButton           matlab.ui.control.Button
        CropEnd                   matlab.ui.control.NumericEditField
        EndLabel                  matlab.ui.control.Label
        CropStart                 matlab.ui.control.NumericEditField
        StartLabel                matlab.ui.control.Label
        ShiftDataDropDown         matlab.ui.control.DropDown
        ForceOffset               matlab.ui.control.NumericEditField
        MovMedianWindow           matlab.ui.control.NumericEditField
        DataPointsforWindowLabel  matlab.ui.control.Label
        MainPlotTab               matlab.ui.container.Tab
        Tab                       matlab.ui.container.Tab
        Button                    matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
    end

    properties (Access = public)
        ProcessDataFigure
        MainFigure
        AxisMarginRatio = 0.1;
        Tcomparison
        TestDataCopy
        StablePath  = 'C:\Users\HPanayirci\OneDrive - Schlumberger\HMP\Workshop\Self Study\Gendorg\Testing';
        SdataPath   = 'C:\Users\HPanayirci\OneDrive - Schlumberger\HMP\Workshop\Self Study\Gendorg\Testing';
        SfigurePath = 'C:\Users\HPanayirci\OneDrive - Schlumberger\HMP\Workshop\Self Study\Gendorg\Testing';
    end 
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Gendorg_InitializeGUI(app);
        end

        % Button pushed function: LoadTableButton
        function LoadTableButtonPushed(app, event)
            Gendorg_LoadTable(app);
        end

        % Button pushed function: SaveTableButton
        function SaveTableButtonPushed(app, event)
            Gendorg_SaveTable(app);
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
           Gendorg_SaveFigure(app);
        end

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)
             Gendorg_GetSelectedData(app);
             Gendorg_PlotData(app);
        end

        % Button pushed function: AddTestButton
        function AddTestButtonPushed(app, event)
           Gendorg_AddTest(app);
        end

        % Button pushed function: DeleteTestButton
        function DeleteTestButtonPushed(app, event)
            Gendorg_DeleteTest(app);
        end

        % Callback function
        function SaveDataButtonPushed(app, event)
            % get selected row
            VrowsToPlot = find(app.UITable.Data.SelectedRows);
            rowID = VrowsToPlot(1);
            % replace existing rig data with modified one
            if ~isempty(app.RigDataChanged)
                app.UITable.Data.RigData{rowID} = app.RigDataChanged;
            end
            %
            app.UITable.Data.Calculated(rowID).DataPrepared = 1;
             % save cutter vel
            app.UITable.Data.Calculated(rowID).VcutterVel = app.VcutterVel;
            %
            AssignColumnColors(app);
        end

        % Button pushed function: EditLimitsButton
        function EditLimitsButtonPushed(app, event)
             if ~app.IgnoreLimitsCheckBox.Value
                 if app.XaxisLimitHigh.Value > app.XaxisLimitLow.Value
                     xlim([app.XaxisLimitLow.Value app.XaxisLimitHigh.Value]);
                 end
                 if app.YaxisLimitHigh.Value > app.YaxisLimitLow.Value
                     ylim([app.YaxisLimitLow.Value app.YaxisLimitHigh.Value]);
                 end
             end
        end

        % Button pushed function: EditTitleLegendButton
        function EditTitleLegendButtonPushed(app, event)
           Gendorg_EditTitleAndLegend(app);        
        end

        % Button pushed function: RemoveOutliersButton
        function RemoveOutliersButtonPushed(app, event)
            Gendorg_RemoveOutliers(app);
        end

        % Button pushed function: SmoothDataButton
        function SmoothDataButtonPushed(app, event)
            Gendorg_SmoothData(app);           
        end

        % Button pushed function: ShiftDataButton
        function ShiftDataButtonPushed(app, event)
            Gendorg_ShiftData(app);
        end

        % Button pushed function: PlotSelectedTestButton
        function PlotSelectedTestButtonPushed(app, event)
           Gendorg_PlotProcessDataFigure(app); 
        end

        % Button pushed function: SaveProcessedDataButton
        function SaveProcessedDataButtonPushed(app, event)
            Gendorg_SaveProcessedData(app); 
        end

        % Button pushed function: CropDataButton
        function CropDataButtonPushed(app, event)
            Gendorg_CropData(app);            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Gendorg_NewFunction(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GendorgInterface = uifigure('Visible', 'off');
            app.GendorgInterface.Name = 'Generic Data Organizer and PostProcessor';
            app.UITable = uitable(app.GendorgInterface);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.TabGroup = uitabgroup(app.GendorgInterface);
            app.ProcessDataTab = uitab(app.TabGroup);
            app.ProcessDataTab.Title = 'Process Data';
            app.ProcessDataButtonGroup = uibuttongroup(app.ProcessDataTab);
            app.ProcessDataButtonGroup.Title = 'Process Data ';
            app.DataPointsforWindowLabel = uilabel(app.ProcessDataButtonGroup);
            app.DataPointsforWindowLabel.Text = {'Data Points '; 'for Window'};
            app.MovMedianWindow = uieditfield(app.ProcessDataButtonGroup, 'numeric');
            app.MovMedianWindow.Value = 100;
            app.ForceOffset = uieditfield(app.ProcessDataButtonGroup, 'numeric');
            app.ForceOffset.ValueDisplayFormat = '%3.1f';
            app.ShiftDataDropDown = uidropdown(app.ProcessDataButtonGroup);
            app.ShiftDataDropDown.Items = {'X data', 'Y data'};
            app.ShiftDataDropDown.Value = 'X data';
            app.StartLabel = uilabel(app.ProcessDataButtonGroup);
            app.StartLabel.Text = 'Start';
            app.CropStart = uieditfield(app.ProcessDataButtonGroup, 'numeric');
            app.EndLabel = uilabel(app.ProcessDataButtonGroup);
            app.EndLabel.Text = 'End';
            app.CropEnd = uieditfield(app.ProcessDataButtonGroup, 'numeric');
            app.ShiftDataButton = uibutton(app.ProcessDataButtonGroup, 'push');
            app.ShiftDataButton.ButtonPushedFcn = createCallbackFcn(app, @ShiftDataButtonPushed, true);
            app.ShiftDataButton.Text = {'Shift '; 'Data'};
            app.CropDataButton = uibutton(app.ProcessDataButtonGroup, 'push');
            app.CropDataButton.ButtonPushedFcn = createCallbackFcn(app, @CropDataButtonPushed, true);
            app.CropDataButton.Text = {'Crop '; 'Data'};
            app.SmoothDataButton = uibutton(app.ProcessDataButtonGroup, 'push');
            app.SmoothDataButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothDataButtonPushed, true);
            app.SmoothDataButton.Text = {'Smooth '; 'Data'};
            app.RemoveOutliersButton = uibutton(app.ProcessDataButtonGroup, 'push');
            app.RemoveOutliersButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveOutliersButtonPushed, true);
            app.RemoveOutliersButton.Text = {'Remove'; 'Outliers'};
            app.SaveProcessedDataButton = uibutton(app.ProcessDataTab, 'push');
            app.SaveProcessedDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProcessedDataButtonPushed, true);
            app.SaveProcessedDataButton.Text = 'Save Processed Data';
            app.PlotSelectedTestButton = uibutton(app.ProcessDataTab, 'push');
            app.PlotSelectedTestButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSelectedTestButtonPushed, true);
            app.PlotSelectedTestButton.Text = 'Plot Selected Test';
            app.MainPlotTab = uitab(app.TabGroup);
            app.MainPlotTab.Title = 'Main Plot';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.TablePanel = uipanel(app.GendorgInterface);
            app.TablePanel.Title = 'Table';
            app.SaveTableButton = uibutton(app.TablePanel, 'push');
            app.SaveTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTableButtonPushed, true);
            app.SaveTableButton.Text = {' Save '; 'Table'};
            app.LoadTableButton = uibutton(app.TablePanel, 'push');
            app.LoadTableButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTableButtonPushed, true);
            app.LoadTableButton.Text = {' Load '; 'Table'};
            app.AddTestButton = uibutton(app.TablePanel, 'push');
            app.AddTestButton.ButtonPushedFcn = createCallbackFcn(app, @AddTestButtonPushed, true);
            app.AddTestButton.Text = {'Add '; 'Test'};
            app.DeleteTestButton = uibutton(app.TablePanel, 'push');
            app.DeleteTestButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteTestButtonPushed, true);
            app.DeleteTestButton.Text = {'Delete '; 'Test'};
            app.PlotsPanel = uipanel(app.GendorgInterface);
            app.PlotsPanel.Title = 'Plots';
            app.PlotDataButton = uibutton(app.PlotsPanel, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Text = 'Plot Data';
            app.SaveFigureButton = uibutton(app.PlotsPanel, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.AdjustAxisLimitsPanel = uipanel(app.GendorgInterface);
            app.AdjustAxisLimitsPanel.Title = 'Adjust Axis Limits';
            app.EditLimitsButton = uibutton(app.AdjustAxisLimitsPanel, 'push');
            app.EditLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @EditLimitsButtonPushed, true);
            app.EditLimitsButton.Text = {'Edit '; 'Limits'};
            app.XLabel = uilabel(app.AdjustAxisLimitsPanel);
            app.XLabel.Text = 'X';
            app.XaxisLimitHigh = uieditfield(app.AdjustAxisLimitsPanel, 'numeric');
            app.XaxisLimitHigh.ValueDisplayFormat = '%3.1f';
            app.XaxisLimitLow = uieditfield(app.AdjustAxisLimitsPanel, 'numeric');
            app.XaxisLimitLow.ValueDisplayFormat = '%3.1f';
            app.YLabel = uilabel(app.AdjustAxisLimitsPanel);
            app.YLabel.Text = 'Y';
            app.YaxisLimitHigh = uieditfield(app.AdjustAxisLimitsPanel, 'numeric');
            app.YaxisLimitHigh.ValueDisplayFormat = '%3.1f';
            app.YaxisLimitLow = uieditfield(app.AdjustAxisLimitsPanel, 'numeric');
            app.YaxisLimitLow.ValueDisplayFormat = '%3.1f';
            app.IgnoreLimitsCheckBox = uicheckbox(app.AdjustAxisLimitsPanel);
            app.IgnoreLimitsCheckBox.Text = 'Ignore Limits';
            app.IgnoreLimitsCheckBox.Value = true;
            app.EditTitleLegendButton = uibutton(app.AdjustAxisLimitsPanel, 'push');
            app.EditTitleLegendButton.ButtonPushedFcn = createCallbackFcn(app, @EditTitleLegendButtonPushed, true);
            app.EditTitleLegendButton.Text = 'Edit Title & Legend';
            app.LegendField = uieditfield(app.AdjustAxisLimitsPanel, 'text');
            app.TitleField = uieditfield(app.AdjustAxisLimitsPanel, 'text');
            app.TitleLabel = uilabel(app.AdjustAxisLimitsPanel);
            app.TitleLabel.Text = 'Title';
            app.LegendLabel = uilabel(app.AdjustAxisLimitsPanel);
            app.LegendLabel.Text = 'Legend';
            app.lowLabel = uilabel(app.AdjustAxisLimitsPanel);
            app.lowLabel.Text = 'low';
            app.highLabel = uilabel(app.AdjustAxisLimitsPanel);
            app.highLabel.Text = 'high';
            % Show the figure after all components are created
            app.GendorgInterface.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gendorg

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GendorgInterface)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GendorgInterface)
        end
    end
end